classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        zCoordinateEditField       matlab.ui.control.NumericEditField
        zCoordinateEditFieldLabel  matlab.ui.control.Label
        YCoordinateEditField       matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel  matlab.ui.control.Label
        XCoordinateEditField       matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel  matlab.ui.control.Label
        Q6EditField                matlab.ui.control.NumericEditField
        Q6EditFieldLabel           matlab.ui.control.Label
        Q5EditField                matlab.ui.control.NumericEditField
        Q5EditFieldLabel           matlab.ui.control.Label
        Q4EditField                matlab.ui.control.NumericEditField
        Q4EditFieldLabel           matlab.ui.control.Label
        Q3EditField                matlab.ui.control.NumericEditField
        Q3EditFieldLabel           matlab.ui.control.Label
        Q2EditField                matlab.ui.control.NumericEditField
        Q2EditFieldLabel           matlab.ui.control.Label
        Q1EditField                matlab.ui.control.NumericEditField
        Q1EditFieldLabel           matlab.ui.control.Label
        SPAWNHUMANATDIFFERENTPLACESButton  matlab.ui.control.Button
        STARTButton                matlab.ui.control.Button
        ESTOPButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            global estop_state 
            if estop_state == 0
                 estop_state = 1;
            elseif estop_state == 1
                 estop_state = 0;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global resume_state
            global estop_state
            if estop_state == 0
                resume_state = 1;
            end 
        end

        % Button pushed function: SPAWNHUMANATDIFFERENTPLACESButton
        function SPAWNHUMANATDIFFERENTPLACESButtonPushed(app, event)
            global manVertexCount
            global manMidpoint
            global manVerts
            global manVertexColors
            global manMesh_h
            global manplotpose
            global manupdatedPoints
            global manf
            global manv
            global mandata
            global X
            global Y
            global sizeMat
            global Z
            global ManCubePoints
            global ManCenterPoint
            global ManRadii
            global x
            global y
            global z
            global spawn_counter
            global estop_state
            if spawn_counter == 0
                spawn_counter = 1;
            elseif spawn_counter == 1
                    spawn_counter = 2;
            elseif spawn_counter == 2
                    spawn_counter = 3;
            elseif spawn_counter == 3
                    spawn_counter = 0;
            end  
            if spawn_counter == 1
                manplotpose = eye(4)*transl(-0.2,0,2)*trotz(-pi/2);
                manupdatedPoints = [manplotpose * [manVerts,ones(manVertexCount,1)]']';
                manMesh_h.Vertices = manupdatedPoints(:,1:3);
                drawnow();

                [X,Y] = meshgrid(-1.2:0.1:-0.8,-0.4:0.1:0.4);
                sizeMat = size(X);
                Z = repmat(1.6,sizeMat(1),sizeMat(2));
                ManCubePoints = [X(:),Y(:),Z(:)];
                ManCubePoints = ManCubePoints + repmat([0,0,0],size(ManCubePoints,1),1);

                ManCenterPoint = [0,-1.9,1000];
                ManRadii = [0.3,0.2,1.3];
                [x,y,z] = ellipsoid(ManCenterPoint(1), ManCenterPoint(2), ManCenterPoint(3), ManRadii(1), ManRadii(2), ManRadii(3));

            elseif (spawn_counter == 2) || (spawn_counter == 0) 
                manplotpose = eye(4)*transl(-0.2,0,1000)*trotz(-pi/2);
                manupdatedPoints = [manplotpose * [manVerts,ones(manVertexCount,1)]']';
                manMesh_h.Vertices = manupdatedPoints(:,1:3);
                drawnow();

                [X,Y] = meshgrid(-1.2:0.1:-0.8,-0.4:0.1:0.4);
                sizeMat = size(X);
                Z = repmat(1000,sizeMat(1),sizeMat(2));
                ManCubePoints = [X(:),Y(:),Z(:)];
                ManCubePoints = ManCubePoints + repmat([0,0,0],size(ManCubePoints,1),1);

                ManCenterPoint = [0,-1.9,1000];
                ManRadii = [0.3,0.2,1.3];
                [x,y,z] = ellipsoid(ManCenterPoint(1), ManCenterPoint(2), ManCenterPoint(3), ManRadii(1), ManRadii(2), ManRadii(3));

            elseif spawn_counter == 3
                manplotpose = eye(4)*transl(0,-1.8,2)*trotz(pi);
                manupdatedPoints = [manplotpose * [manVerts,ones(manVertexCount,1)]']';
                manMesh_h.Vertices = manupdatedPoints(:,1:3);
                drawnow();

                [X,Y] = meshgrid(-1.2:0.1:-0.8,-0.4:0.1:0.4);
                sizeMat = size(X);
                Z = repmat(1000,sizeMat(1),sizeMat(2));
                ManCubePoints = [X(:),Y(:),Z(:)];
                ManCubePoints = ManCubePoints + repmat([0,0,0],size(ManCubePoints,1),1);

                ManCenterPoint = [0,-1.9,1.3];
                ManRadii = [0.3,0.2,1.3];
                [x,y,z] = ellipsoid(ManCenterPoint(1), ManCenterPoint(2), ManCenterPoint(3), ManRadii(1), ManRadii(2), ManRadii(3));
                                                                                                            estop_state = 1;
            end 
        end

        % Callback function
        function STARTCOOKINGButtonPushed(app, event)

        end

        % Value changed function: Q1EditField
        function Q1EditFieldValueChanged(app, event)
            value = app.Q1EditField.Value;
            global Q1
            Q1 = deg2rad(value);
            global previous_counter
            previous_counter = previous_counter+1;
        end

        % Value changed function: Q2EditField
        function Q2EditFieldValueChanged(app, event)
            value = app.Q2EditField.Value;
            global Q2
            Q2 = deg2rad(value);
            global previous_counter
            previous_counter = previous_counter+1;
        end

        % Value changed function: Q3EditField
        function Q3EditFieldValueChanged(app, event)
            value = app.Q3EditField.Value;
            global Q3
            Q3 = deg2rad(value);
            global previous_counter
            previous_counter = previous_counter+1;
        end

        % Value changed function: Q4EditField
        function Q4EditFieldValueChanged(app, event)
            value = app.Q4EditField.Value;
            global Q4
            Q4 = deg2rad(value);
            global previous_counter
            previous_counter = previous_counter+1;
        end

        % Value changed function: Q5EditField
        function Q5EditFieldValueChanged(app, event)
            value = app.Q5EditField.Value;
            global Q5
            Q5 = deg2rad(value);
            global previous_counter
            previous_counter = previous_counter+1;
        end

        % Value changed function: Q6EditField
        function Q6EditFieldValueChanged(app, event)
            value = app.Q6EditField.Value;
            global Q6
            Q6 = deg2rad(value);
            global previous_counter
            previous_counter = previous_counter+1;
        end

        % Value changed function: XCoordinateEditField
        function XCoordinateEditFieldValueChanged(app, event)
            value = app.XCoordinateEditField.Value;
            global X_Coordinate
            X_Coordinate = value;
            global previous_counter2
            previous_counter2 = previous_counter2+1;
        end

        % Value changed function: YCoordinateEditField
        function YCoordinateEditFieldValueChanged(app, event)
            value = app.YCoordinateEditField.Value;
            global Y_Coordinate
            Y_Coordinate = value;
            global previous_counter2
            previous_counter2 = previous_counter2+1;
        end

        % Value changed function: zCoordinateEditField
        function zCoordinateEditFieldValueChanged(app, event)
            value = app.zCoordinateEditField.Value;
            global Z_Coordinate
            Z_Coordinate = value;
            global previous_counter2
            previous_counter2 = previous_counter2+1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SPAWNHUMANATDIFFERENTPLACESButton = uibutton(app.UIFigure, 'push');
            app.SPAWNHUMANATDIFFERENTPLACESButton.ButtonPushedFcn = createCallbackFcn(app, @SPAWNHUMANATDIFFERENTPLACESButtonPushed, true);
            app.SPAWNHUMANATDIFFERENTPLACESButton.Text = ' SPAWN HUMAN AT DIFFERENT PLACES ';
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.ValueChangedFcn = createCallbackFcn(app, @Q1EditFieldValueChanged, true);
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.ValueChangedFcn = createCallbackFcn(app, @Q2EditFieldValueChanged, true);
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField.ValueChangedFcn = createCallbackFcn(app, @Q3EditFieldValueChanged, true);
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditField.ValueChangedFcn = createCallbackFcn(app, @Q4EditFieldValueChanged, true);
            app.Q5EditFieldLabel = uilabel(app.UIFigure);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditField.ValueChangedFcn = createCallbackFcn(app, @Q5EditFieldValueChanged, true);
            app.Q6EditFieldLabel = uilabel(app.UIFigure);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q6EditField.ValueChangedFcn = createCallbackFcn(app, @Q6EditFieldValueChanged, true);
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.XCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordinateEditFieldValueChanged, true);
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @YCoordinateEditFieldValueChanged, true);
            app.zCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.zCoordinateEditFieldLabel.Text = 'z Coordinate';
            app.zCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.zCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @zCoordinateEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end